classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        frwagenUIFigure             matlab.ui.Figure
        TabGroup2                   matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        UITable2                    matlab.ui.control.Table
        Tab2                        matlab.ui.container.Tab
        GuardarButton               matlab.ui.control.Button
        EncontrardiferenciasButton  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        SeleccionararchivoButton    matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        xlsxMenu_2                  matlab.ui.container.Menu
        cvsMenu                     matlab.ui.container.Menu
        txtMenu_2                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tabs % Description
        TabGroup
        nTabs = 1
        UITable
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            monitor = get(0,'ScreenSize');
            interfaz = app.frwagenUIFigure.Position;
            x = monitor(3)/2-interfaz(3)/2;
            y = monitor(4)/2-interfaz(4)/2;
            app.frwagenUIFigure.Position = [x y interfaz(3) interfaz(4)];
            drawnow;
            app.frwagenUIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv';'*.txt';'*.xlsx'},'File Selector');
            tabla = readtable(file,VariableNamingRule="preserve");
            app.UITable2.Data = tabla;
            app.UITable2.ColumnName = tabla.Properties.VariableNames;
%             %app.frwagenUIFigure = uifigure();
%             % Set up tab group
%             app.TabGroup = uitabgroup(app.frwagenUIFigure);
%             % app.TabGroup.Position = [300 300 300 300];   % not needed for demo
%             % Specify the total number of tabs
%             app.nTabs = app.nTabs+1; % including MainTab
%             % Set up the main tab
%             app.tabs = cell(1,app.nTabs);
%             app.tabs{app.nTabs} = uitab(app.TabGroup,'Title',file); %create the main tab
%             % Create additional tabs
%             for i = 2:app.nTabs %create the number of tabs entered by the user
%                 app.tabs{i} = uitab(app.TabGroup,'Title',['Tab' num2str(i-1)]);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.frwagenUIFigure = uifigure('Visible', 'off');
            app.frwagenUIFigure.Name = 'fürwagen';
            app.frwagenUIFigure.Icon = fullfile(pathToMLAPP, 'Manual de marca - Furwagen', 'Logos 2000w', 'Logo V.png');
            app.SeleccionararchivoButton = uibutton(app.frwagenUIFigure, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.Image = uiimage(app.frwagenUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Manual de marca - Furwagen', 'Logos 2000w', 'Logo.png');
            app.EncontrardiferenciasButton = uibutton(app.frwagenUIFigure, 'push');
            app.EncontrardiferenciasButton.Text = 'Encontrar diferencias';
            app.GuardarButton = uibutton(app.frwagenUIFigure, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.TabGroup2 = uitabgroup(app.frwagenUIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UITable2 = uitable(app.Tab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.ContextMenu = uicontextmenu(app.frwagenUIFigure);
            app.xlsxMenu_2 = uimenu(app.ContextMenu);
            app.xlsxMenu_2.Text = '.xlsx';
            app.cvsMenu = uimenu(app.ContextMenu);
            app.cvsMenu.Text = '.cvs';
            app.txtMenu_2 = uimenu(app.ContextMenu);
            app.txtMenu_2.Text = '.txt';
            % Show the figure after all components are created
            app.frwagenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.frwagenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.frwagenUIFigure)
        end
    end
end